classdef APPv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        GridLayout5       matlab.ui.container.GridLayout
        Zlevel            matlab.ui.control.Spinner
        ZlevelLabel       matlab.ui.control.Label
        GridLayout4       matlab.ui.container.GridLayout
        saveB             matlab.ui.control.Button
        loadB             matlab.ui.control.Button
        GridLayout3       matlab.ui.container.GridLayout
        cirLabelScl       matlab.ui.control.Label
        cirScl            matlab.ui.control.NumericEditField
        scalefactorLabel  matlab.ui.control.Label
        scainputS         matlab.ui.control.Spinner
        GridLayout2       matlab.ui.container.GridLayout
        cirLabelOrg       matlab.ui.control.Label
        cirOrg            matlab.ui.control.NumericEditField
        scaP              matlab.ui.control.UIAxes
        orP               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TR % Description
        % Property added to store the link object for synced 3D rotation
        LinkObject
        SliceLine
    end
    
    methods (Access = private)

% Helper function to calculate circumference
        function [circ, hullPts, zLevel] = calculateCircumference(app, P)
            % Read dynamically from the single Zlevel spinner
            zLevel = app.Zlevel.Value;
            Zmin = zLevel - 0.0025; 
            Zmax = zLevel + 0.0025; 
            
            % Extract slice points within the Z-range
            sliceIdx = (P(:, 3) >= Zmin) & (P(:, 3) <= Zmax);
            slicePts = P(sliceIdx, 1:2);
            
            % Check if enough points exist to form a polygon
            if size(slicePts, 1) < 3
                circ = 0;
                hullPts = [];
                return;
            end
            
            % Calculate Convex Hull
            K = convhull(slicePts(:, 1), slicePts(:, 2));
            hullPts = slicePts(K, :);
            
            % Calculate Circumference (sum of Euclidean distances between points)
            d = diff(hullPts, 1, 1);
            circ = sum(sqrt(sum(d.^2, 2)));
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.AutoResizeChildren = 'on';
        end

        % Button pushed function: loadB
        function loadBPushed(app, event)
            % --- STL Import and Visualization ---
            [file, path] = uigetfile('*.stl', 'Select STL file');
            if isequal(file, 0)
                return;
            end
            fascicle_file = fullfile(path, file);
            app.TR = stlread(fascicle_file);
            % Extract P
            P = app.TR.Points;

            % --- DYNAMIC SPINNER INITIALIZATION ---
            zMinReal = min(P(:, 3));
            zMaxReal = max(P(:, 3));
            zHeight = zMaxReal - zMinReal;

            % Set boundaries based on the loaded 3D model
            app.Zlevel.Limits = [zMinReal, zMaxReal];
            % Set scroll step to 1% of total height
            app.Zlevel.Step = zHeight / 100;
            % Start the spinner exactly in the middle of the object
            app.Zlevel.Value = zMinReal + 0.5 * zHeight;
            % --------------------------------------

            % Clear the axes before plotting new data
            cla(app.orP);
            cla(app.scaP);

            % Plot the STL geometry
            patch(app.orP, ...
                'Faces', app.TR.ConnectivityList, ...
                'Vertices', P, ... 
                'FaceColor', [1 0.1 0.1], ...
                'EdgeColor', 'none', ...
                'FaceAlpha', 0.5);

            app.orP.DataAspectRatio = [1 1 1];
            app.orP.PlotBoxAspectRatio = [1 1 1];
            app.scaP.DataAspectRatio = [1 1 1];
            app.scaP.PlotBoxAspectRatio = [1 1 1];
            camlight(app.orP);
            lighting(app.orP, 'gouraud');

            % Link ONLY the cameras (View) so rotating one rotates the other
            app.LinkObject = linkprop([app.orP, app.scaP], {'View'});

            % --- Circumference Calculation (Original) ---
            [currentCirc, hullPts, zLevel] = app.calculateCircumference(P);
            app.cirOrg.Value = currentCirc;

            % --- Overlay Slice Visualization ---
            if ~isempty(hullPts)
                hold(app.orP, 'on');
                % Plot the blue hull outline at the slice level
                plot3(app.orP, hullPts(:, 1), hullPts(:, 2), zLevel * ones(size(hullPts, 1), 1), ...
                    'b', 'LineWidth', 2);
                hold(app.orP, 'off');
            end
            title(app.orP, file, 'Interpreter', 'none');

            % Force the scaling callback to run to populate the scaled plot and cirScl
            app.scainputSValueChanged([]);

        end

        % Value changed function: scainputS
        function scainputSValueChanged(app, event)
            % Check if STL is loaded
            if isempty(app.TR)
                return;
            end

            % Get scale factor from spinner
            scaleFactor = app.scainputS.Value;

            % Original points
            P = app.TR.Points;
            F = app.TR.ConnectivityList;

            % Compute center
            centerXY = mean(P(:,1:2), 1);

            % Apply scaling (XY only)
            P_new = P;
            P_new(:,1) = centerXY(1) + scaleFactor * (P(:,1) - centerXY(1));
            P_new(:,2) = centerXY(2) + scaleFactor * (P(:,2) - centerXY(2));

            % Clear the scaled plot
            cla(app.scaP);

            % 1. Plot the ORIGINAL STL and save it to variable p1
            p1 = patch(app.scaP, ...
                'Faces', F, ...
                'Vertices', P, ...
                'FaceColor', [1 0.1 0.1], ... 
                'EdgeColor', 'none', ...
                'FaceAlpha', 0.2);            

            % 2. Plot the SCALED STL and save it to variable p2
            p2 = patch(app.scaP, ...
                'Faces', F, ...
                'Vertices', P_new, ...
                'FaceColor', [0.1 0.3 1], ... 
                'EdgeColor', 'none', ...
                'FaceAlpha', 0.6);            

            % 3. Create legend explicitly for p1 and p2, and turn off AutoUpdate
            legend(app.scaP, [p1, p2], {'Original Size', 'Scaled Size'}, ...
                'Location', 'northeast', 'AutoUpdate', 'off');

            % Re-enforce the rigid rules after redrawing
            app.scaP.DataAspectRatio = [1 1 1];
            app.scaP.PlotBoxAspectRatio = [1 1 1];
            camlight(app.scaP);
            lighting(app.scaP, 'gouraud');

            % --- Circumference Calculation (Scaled) ---
            [scaledCirc, scaledHull, zLevel] = app.calculateCircumference(P_new);
            app.cirScl.Value = scaledCirc;

            % --- Overlay Scaled Slice Visualization ---
            if ~isempty(scaledHull)
                hold(app.scaP, 'on');
                % Plot the dark blue hull outline at the slice level
                % (Removed HandleVisibility off so cla() can delete it next time)
                plot3(app.scaP, scaledHull(:, 1), scaledHull(:, 2), zLevel * ones(size(scaledHull, 1), 1), ...
                    'Color', [0 0 0.5], 'LineWidth', 2); 
                hold(app.scaP, 'off');
            end
        end

        % Button pushed function: saveB
        function saveBButtonPushed(app, event)
            % 1. Check if an STL is actually loaded
            if isempty(app.TR)
                uialert(app.UIFigure, 'Please load an STL file first.', 'No Data');
                return;
            end
            
            % 2. Get the current scale factor
            scaleFactor = app.scainputS.Value;
            
            % 3. Recompute the scaled points (exact same logic as your spinner)
            P = app.TR.Points;
            F = app.TR.ConnectivityList;
            centerXY = mean(P(:,1:2), 1);
            
            P_new = P;
            P_new(:,1) = centerXY(1) + scaleFactor * (P(:,1) - centerXY(1));
            P_new(:,2) = centerXY(2) + scaleFactor * (P(:,2) - centerXY(2));
            
            % 4. Create a new triangulation object (required by stlwrite)
            scaledTR = triangulation(F, P_new);
            
            % 5. Create a suggested file name including the scale factor
            % Replace decimal point with an underscore (e.g., 1.5 becomes 1_5)
            scaleStr = strrep(num2str(scaleFactor), '.', '_');
            defaultFileName = sprintf('scaled_factor_%s.stl', scaleStr);
            
            % 6. Open the Save File dialog box
            [file, path] = uiputfile('*.stl', 'Save Scaled STL As', defaultFileName);
            
            % 7. Check if the user clicked 'Cancel'
            if isequal(file, 0)
                return;
            end
            
            % 8. Save the new STL file
            fullFilePath = fullfile(path, file);
            stlwrite(scaledTR, fullFilePath);
            
            % Optional: Show a quick success message
            uialert(app.UIFigure, 'Scaled STL saved successfully!', 'Success');
        end

        % Value changed function: Zlevel
        function ZlevelValueChanged(app, event)
            value = app.Zlevel.Value;
            % Check if STL is loaded
            if isempty(app.TR)
                return;
            end

            % 1. Re-plot Original Axis
            P = app.TR.Points;
            cla(app.orP);

            patch(app.orP, ...
                'Faces', app.TR.ConnectivityList, ...
                'Vertices', P, ... 
                'FaceColor', [1 0.1 0.1], ...
                'EdgeColor', 'none', ...
                'FaceAlpha', 0.5);

            app.orP.DataAspectRatio = [1 1 1];
            app.orP.PlotBoxAspectRatio = [1 1 1];
            camlight(app.orP);
            lighting(app.orP, 'gouraud');

            % Recalculate original circumference with the new Z level
            [currentCirc, hullPts, zLevel] = app.calculateCircumference(P);
            app.cirOrg.Value = currentCirc;

            if ~isempty(hullPts)
                hold(app.orP, 'on');
                plot3(app.orP, hullPts(:, 1), hullPts(:, 2), zLevel * ones(size(hullPts, 1), 1), ...
                    'b', 'LineWidth', 2);
                hold(app.orP, 'off');
            end

            % 2. Force update of the scaled plot (it will automatically use the new Zlevel)
            app.scainputSValueChanged([]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.orP = uiaxes(app.GridLayout);
            title(app.orP, 'Title')
            xlabel(app.orP, 'X')
            ylabel(app.orP, 'Y')
            zlabel(app.orP, 'Z')
            app.scaP = uiaxes(app.GridLayout);
            title(app.scaP, 'Original vs. Scaled')
            xlabel(app.scaP, 'X')
            ylabel(app.scaP, 'Y')
            zlabel(app.scaP, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.cirOrg = uieditfield(app.GridLayout2, 'numeric');
            app.cirLabelOrg = uilabel(app.GridLayout2);
            app.cirLabelOrg.Text = 'cir ';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.scainputS = uispinner(app.GridLayout3);
            app.scainputS.Step = 0.1;
            app.scainputS.Limits = [0.1 2.5];
            app.scainputS.ValueChangedFcn = createCallbackFcn(app, @scainputSValueChanged, true);
            app.scainputS.Value = 1.2;
            app.scalefactorLabel = uilabel(app.GridLayout3);
            app.scalefactorLabel.Text = 'scale factor ';
            app.cirScl = uieditfield(app.GridLayout3, 'numeric');
            app.cirLabelScl = uilabel(app.GridLayout3);
            app.cirLabelScl.Text = 'cir ';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.loadB = uibutton(app.GridLayout4, 'push');
            app.loadB.ButtonPushedFcn = createCallbackFcn(app, @loadBPushed, true);
            app.loadB.Text = 'Load';
            app.saveB = uibutton(app.GridLayout4, 'push');
            app.saveB.ButtonPushedFcn = createCallbackFcn(app, @saveBButtonPushed, true);
            app.saveB.Text = 'Save';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.ZlevelLabel = uilabel(app.GridLayout5);
            app.ZlevelLabel.Text = 'Zlevel';
            app.Zlevel = uispinner(app.GridLayout5);
            app.Zlevel.ValueDisplayFormat = '%11.5g';
            app.Zlevel.ValueChangedFcn = createCallbackFcn(app, @ZlevelValueChanged, true);
            app.Zlevel.Value = 1.3975;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APPv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end